classdef basedef < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BasisDefinitionUIFigure       matlab.ui.Figure
        EnterasafunctionofXX0atstartingpointofthedivisionLabel  matlab.ui.control.Label
        TextArea_6                    matlab.ui.control.TextArea
        Label_5                       matlab.ui.control.Label
        TextArea_5                    matlab.ui.control.TextArea
        Label_4                       matlab.ui.control.Label
        TextArea_4                    matlab.ui.control.TextArea
        Label_3                       matlab.ui.control.Label
        TextArea_3                    matlab.ui.control.TextArea
        Label_2                       matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
        BasistypeDropDown             matlab.ui.control.DropDown
        BasistypeDropDownLabel        matlab.ui.control.Label
        DoneButton                    matlab.ui.control.Button
        OrderSpinner_6                matlab.ui.control.Spinner
        OrderSpinner_6Label           matlab.ui.control.Label
        ShearalongzaxisCheckBox       matlab.ui.control.CheckBox
        OrderSpinner_5                matlab.ui.control.Spinner
        OrderSpinner_5Label           matlab.ui.control.Label
        ShearalongyaxisCheckBox       matlab.ui.control.CheckBox
        OrderSpinner_4                matlab.ui.control.Spinner
        OrderSpinner_4Label           matlab.ui.control.Label
        ElongationalongxaxisCheckBox  matlab.ui.control.CheckBox
        OrderSpinner_3                matlab.ui.control.Spinner
        OrderSpinner_3Label           matlab.ui.control.Label
        OrderSpinner_2                matlab.ui.control.Spinner
        OrderSpinner_2Label           matlab.ui.control.Label
        BendingaboutzaxisCheckBox     matlab.ui.control.CheckBox
        BendingaboutyaxisCheckBox     matlab.ui.control.CheckBox
        OrderSpinner                  matlab.ui.control.Spinner
        OrderSpinnerLabel             matlab.ui.control.Label
        TorsionaboutxaxisCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        done % Description
        n_ele
        FEMO
    end
    
    methods (Access = public)
        
        function closewindow(app)
            while app.done==0
                pause(0.05);
            end
            close(app.BasisDefinitionUIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, i, j)
                       % Create ChoosebasisforthesoftpieceLabel
            Label1          = uilabel(app.BasisDefinitionUIFigure);
            Label1.Position = [130 330 200 22];
            Label1.Text     = ['Choose basis for link ',num2str(i),' division ',num2str(j)]; 
            app.done=0;
            app.closewindow;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            Phi_dof     = zeros(6,1);
            Phi_odr     = zeros(6,1);
            xi_stars = cell(6,1);
            if app.TorsionaboutxaxisCheckBox.Value
                Phi_dof(1) = 1;
            end
            if app.BendingaboutyaxisCheckBox.Value
                Phi_dof(2) = 1;
            end
            if app.BendingaboutzaxisCheckBox.Value
                Phi_dof(3) = 1;
            end
            if app.ElongationalongxaxisCheckBox.Value
                Phi_dof(4) = 1;
            end
            if app.ShearalongyaxisCheckBox.Value
                Phi_dof(5) = 1;
            end
            if app.ShearalongzaxisCheckBox.Value
                Phi_dof(6) = 1;
            end
            Phi_odr(1) = app.OrderSpinner.Value;
            Phi_odr(2) = app.OrderSpinner_2.Value;
            Phi_odr(3) = app.OrderSpinner_3.Value;
            Phi_odr(4) = app.OrderSpinner_4.Value;
            Phi_odr(5) = app.OrderSpinner_5.Value;
            Phi_odr(6) = app.OrderSpinner_6.Value;
            
            xi_stars{1} = app.TextArea.Value;
            xi_stars{2} = app.TextArea_2.Value;
            xi_stars{3} = app.TextArea_3.Value;
            xi_stars{4} = app.TextArea_4.Value;
            xi_stars{5} = app.TextArea_5.Value;
            xi_stars{6} = app.TextArea_6.Value;
            
            Type = app.BasistypeDropDown.Value;
            SubClass = app.FEMO;
            
            if strcmp(app.BasistypeDropDown.Value,'FEM Like')||strcmp(app.BasistypeDropDown.Value,'Hermite Spline')||strcmp(app.BasistypeDropDown.Value,'Gaussian Element')||strcmp(app.BasistypeDropDown.Value,'Inverse Multi-quadratic')
                Phi_odr = app.n_ele*ones(6,1);
            end
           
            save('Base_properties.mat','Type','Phi_dof','Phi_odr','xi_stars','SubClass')
            app.done=1;
        end

        % Value changed function: BasistypeDropDown
        function BasistypeDropDownValueChanged(app, event)
            value = app.BasistypeDropDown.Value;
            if strcmp(value,'Custom Independent') 
                set(app.TorsionaboutxaxisCheckBox,'Enable','off')
                set(app.BendingaboutyaxisCheckBox,'Enable','off')
                set(app.BendingaboutzaxisCheckBox,'Enable','off')
                set(app.ElongationalongxaxisCheckBox,'Enable','off')
                set(app.ShearalongyaxisCheckBox,'Enable','off')
                set(app.ShearalongzaxisCheckBox,'Enable','off')
                
                set(app.OrderSpinner,'Enable','off')
                set(app.OrderSpinner_2,'Enable','off')
                set(app.OrderSpinner_3,'Enable','off')
                set(app.OrderSpinner_4,'Enable','off')
                set(app.OrderSpinner_5,'Enable','off')
                set(app.OrderSpinner_6,'Enable','off')
                
                set(app.OrderSpinnerLabel,'Enable','off')
                set(app.OrderSpinner_2Label,'Enable','off')
                set(app.OrderSpinner_3Label,'Enable','off')
                set(app.OrderSpinner_4Label,'Enable','off')
                set(app.OrderSpinner_5Label,'Enable','off')
                set(app.OrderSpinner_6Label,'Enable','off')
    
            elseif strcmp(value,'FEM Like')

                set(app.OrderSpinner,'Enable','off')
                set(app.OrderSpinner_2,'Enable','off')
                set(app.OrderSpinner_3,'Enable','off')
                set(app.OrderSpinner_4,'Enable','off')
                set(app.OrderSpinner_5,'Enable','off')
                set(app.OrderSpinner_6,'Enable','off')
                
                set(app.OrderSpinnerLabel,'Enable','off')
                set(app.OrderSpinner_2Label,'Enable','off')
                set(app.OrderSpinner_3Label,'Enable','off')
                set(app.OrderSpinner_4Label,'Enable','off')
                set(app.OrderSpinner_5Label,'Enable','off')
                set(app.OrderSpinner_6Label,'Enable','off')
                
                quest = 'FEM element order:';
                app.FEMO  = questdlg(quest,'Order','Linear','Quadratic','Cubic','Linear');
                prompt           = {'Enter number of elements:'};
                dlgtitle         = 'Number of Elements';
                definput         = {num2str(1)};
                opts.Interpreter = 'tex';
                ans_act          = inputdlg(prompt,dlgtitle,[1 50],definput,opts);
                app.n_ele        = str2double(ans_act{1});
            elseif strcmp(value,'Hermite Spline')||strcmp(value,'Gaussian Element')||strcmp(value,'Inverse Multi-quadratic')

                set(app.OrderSpinner,'Enable','off')
                set(app.OrderSpinner_2,'Enable','off')
                set(app.OrderSpinner_3,'Enable','off')
                set(app.OrderSpinner_4,'Enable','off')
                set(app.OrderSpinner_5,'Enable','off')
                set(app.OrderSpinner_6,'Enable','off')
                
                set(app.OrderSpinnerLabel,'Enable','off')
                set(app.OrderSpinner_2Label,'Enable','off')
                set(app.OrderSpinner_3Label,'Enable','off')
                set(app.OrderSpinner_4Label,'Enable','off')
                set(app.OrderSpinner_5Label,'Enable','off')
                set(app.OrderSpinner_6Label,'Enable','off')
                
                prompt           = {'Enter number of elements:'};
                dlgtitle         = 'Number of Elements';
                definput         = {num2str(1)};
                opts.Interpreter = 'tex';
                ans_act          = inputdlg(prompt,dlgtitle,[1 50],definput,opts);
                app.n_ele        = str2double(ans_act{1});
            else
                set(app.TorsionaboutxaxisCheckBox,'Enable','on')
                set(app.BendingaboutyaxisCheckBox,'Enable','on')
                set(app.BendingaboutzaxisCheckBox,'Enable','on')
                set(app.ElongationalongxaxisCheckBox,'Enable','on')
                set(app.ShearalongyaxisCheckBox,'Enable','on')
                set(app.ShearalongzaxisCheckBox,'Enable','on')
                
                set(app.OrderSpinner,'Enable','on')
                set(app.OrderSpinner_2,'Enable','on')
                set(app.OrderSpinner_3,'Enable','on')
                set(app.OrderSpinner_4,'Enable','on')
                set(app.OrderSpinner_5,'Enable','on')
                set(app.OrderSpinner_6,'Enable','on')
                
                set(app.OrderSpinnerLabel,'Enable','on')
                set(app.OrderSpinner_2Label,'Enable','on')
                set(app.OrderSpinner_3Label,'Enable','on')
                set(app.OrderSpinner_4Label,'Enable','on')
                set(app.OrderSpinner_5Label,'Enable','on')
                set(app.OrderSpinner_6Label,'Enable','on')
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BasisDefinitionUIFigure = uifigure('Visible', 'off');
            app.BasisDefinitionUIFigure.Name = 'Basis Definition';
            app.TorsionaboutxaxisCheckBox = uicheckbox(app.BasisDefinitionUIFigure);
            app.TorsionaboutxaxisCheckBox.Text = 'Torsion about x axis';
            app.OrderSpinnerLabel = uilabel(app.BasisDefinitionUIFigure);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.BasisDefinitionUIFigure);
            app.OrderSpinner.Limits = [0 Inf];
            app.OrderSpinner.RoundFractionalValues = 'on';
            app.OrderSpinner.ValueDisplayFormat = '%.0f';
            app.BendingaboutyaxisCheckBox = uicheckbox(app.BasisDefinitionUIFigure);
            app.BendingaboutyaxisCheckBox.Text = 'Bending about y axis';
            app.BendingaboutzaxisCheckBox = uicheckbox(app.BasisDefinitionUIFigure);
            app.BendingaboutzaxisCheckBox.Text = 'Bending about z axis';
            app.OrderSpinner_2Label = uilabel(app.BasisDefinitionUIFigure);
            app.OrderSpinner_2Label.Text = 'Order';
            app.OrderSpinner_2 = uispinner(app.BasisDefinitionUIFigure);
            app.OrderSpinner_2.Limits = [0 Inf];
            app.OrderSpinner_2.RoundFractionalValues = 'on';
            app.OrderSpinner_2.ValueDisplayFormat = '%.0f';
            app.OrderSpinner_3Label = uilabel(app.BasisDefinitionUIFigure);
            app.OrderSpinner_3Label.Text = 'Order';
            app.OrderSpinner_3 = uispinner(app.BasisDefinitionUIFigure);
            app.OrderSpinner_3.Limits = [0 Inf];
            app.OrderSpinner_3.RoundFractionalValues = 'on';
            app.OrderSpinner_3.ValueDisplayFormat = '%.0f';
            app.ElongationalongxaxisCheckBox = uicheckbox(app.BasisDefinitionUIFigure);
            app.ElongationalongxaxisCheckBox.Text = 'Elongation along x axis';
            app.OrderSpinner_4Label = uilabel(app.BasisDefinitionUIFigure);
            app.OrderSpinner_4Label.Text = 'Order';
            app.OrderSpinner_4 = uispinner(app.BasisDefinitionUIFigure);
            app.OrderSpinner_4.Limits = [0 Inf];
            app.OrderSpinner_4.RoundFractionalValues = 'on';
            app.OrderSpinner_4.ValueDisplayFormat = '%.0f';
            app.ShearalongyaxisCheckBox = uicheckbox(app.BasisDefinitionUIFigure);
            app.ShearalongyaxisCheckBox.Text = 'Shear along y axis';
            app.OrderSpinner_5Label = uilabel(app.BasisDefinitionUIFigure);
            app.OrderSpinner_5Label.Text = 'Order';
            app.OrderSpinner_5 = uispinner(app.BasisDefinitionUIFigure);
            app.OrderSpinner_5.Limits = [0 Inf];
            app.OrderSpinner_5.RoundFractionalValues = 'on';
            app.OrderSpinner_5.ValueDisplayFormat = '%.0f';
            app.ShearalongzaxisCheckBox = uicheckbox(app.BasisDefinitionUIFigure);
            app.ShearalongzaxisCheckBox.Text = 'Shear along z axis';
            app.OrderSpinner_6Label = uilabel(app.BasisDefinitionUIFigure);
            app.OrderSpinner_6Label.Text = 'Order';
            app.OrderSpinner_6 = uispinner(app.BasisDefinitionUIFigure);
            app.OrderSpinner_6.Limits = [0 Inf];
            app.OrderSpinner_6.RoundFractionalValues = 'on';
            app.OrderSpinner_6.ValueDisplayFormat = '%.0f';
            app.DoneButton = uibutton(app.BasisDefinitionUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.BasistypeDropDownLabel = uilabel(app.BasisDefinitionUIFigure);
            app.BasistypeDropDownLabel.Text = 'Basis type';
            app.BasistypeDropDown = uidropdown(app.BasisDefinitionUIFigure);
            app.BasistypeDropDown.Items = {'Monomial', 'Legendre Polynomial', 'Chebychev', 'Fourier', 'FEM Like', 'Hermite Spline', 'Gaussian Element', 'Inverse Multi-quadratic', 'Custom Basis'};
            app.BasistypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BasistypeDropDownValueChanged, true);
            app.BasistypeDropDown.Value = 'Legendre Polynomial';
            app.TextAreaLabel = uilabel(app.BasisDefinitionUIFigure);
            app.TextAreaLabel.Text = 'ξ*(1)';
            app.TextArea = uitextarea(app.BasisDefinitionUIFigure);
            app.TextArea.Value = {'0'};
            app.Label = uilabel(app.BasisDefinitionUIFigure);
            app.Label.Text = 'ξ*(2)';
            app.TextArea_2 = uitextarea(app.BasisDefinitionUIFigure);
            app.TextArea_2.Value = {'0'};
            app.Label_2 = uilabel(app.BasisDefinitionUIFigure);
            app.Label_2.Text = 'ξ*(3)';
            app.TextArea_3 = uitextarea(app.BasisDefinitionUIFigure);
            app.TextArea_3.Value = {'0'};
            app.Label_3 = uilabel(app.BasisDefinitionUIFigure);
            app.Label_3.Text = 'ξ*(4)';
            app.TextArea_4 = uitextarea(app.BasisDefinitionUIFigure);
            app.TextArea_4.Value = {'1'};
            app.Label_4 = uilabel(app.BasisDefinitionUIFigure);
            app.Label_4.Text = 'ξ*(5)';
            app.TextArea_5 = uitextarea(app.BasisDefinitionUIFigure);
            app.TextArea_5.Value = {'0'};
            app.Label_5 = uilabel(app.BasisDefinitionUIFigure);
            app.Label_5.Text = 'ξ*(6)';
            app.TextArea_6 = uitextarea(app.BasisDefinitionUIFigure);
            app.TextArea_6.Value = {'0'};
            app.EnterasafunctionofXX0atstartingpointofthedivisionLabel = uilabel(app.BasisDefinitionUIFigure);
            app.EnterasafunctionofXX0atstartingpointofthedivisionLabel.Text = 'Enter reference strain (ξ*) as a function of normalized X (varies from 0 to 1)';
            % Show the figure after all components are created
            app.BasisDefinitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basedef(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BasisDefinitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BasisDefinitionUIFigure)
        end
    end
end